classdef sofview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sofview                        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        NewTab                         matlab.ui.container.Tab
        GeometrytemplateDropDownLabel  matlab.ui.control.Label
        GeomTemplate                   matlab.ui.control.DropDown
        TextArea                       matlab.ui.control.TextArea
        GeomTab                        matlab.ui.container.Tab
        RegionsListBoxLabel            matlab.ui.control.Label
        RegionsListBox                 matlab.ui.control.ListBox
        MeshTab                        matlab.ui.container.Tab
        SupportsTab                    matlab.ui.container.Tab
        LoadsTab                       matlab.ui.container.Tab
        ResultsTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        Tree                           matlab.ui.container.Tree
        ModelNode                      matlab.ui.container.TreeNode
        GeometryNode                   matlab.ui.container.TreeNode
        SupportsNode                   matlab.ui.container.TreeNode
        LoadsNode                      matlab.ui.container.TreeNode
        StatusmessageLabel             matlab.ui.control.Label
        StatusInfo                     matlab.ui.control.ListBox
    end


    properties (Access = private)
        model SofModel % the model managed via GUI 
        projDir % path to project directory
        projFile % name of project file
    end

    methods (Access = private)
    
        function displayMessage(app, msg)
            % Display message in status area.
            app.StatusInfo.Items = [app.StatusInfo.Items,msg]; 
            scroll(app.StatusInfo,'bottom');
        end
        
        function saveProject(app)
           msg = sprintf('Saving project in %s', fullfile(app.projPath, ...
               app.projFile));
           app.displayMessage(msg); 
        end
        
        function selectGeomTemplate(app, name)
            msg = sprintf('Selecting geom: %s', name);
            app.model.setGeometricModel(name);
            app.displayMessage(msg);
        end
        
        function selectProjectFile(app)
            [file,path] = uigetfile('*.sof');
            if isequal(file,0)
                app.displayMessage('Selecting file canceled');
            else
                app.projDir = path;
                app.projFile = file;
            end
        end
        
        function updateGeomTab(app)
            regions = app.model.regionNames();
            app.RegionsListBox.Items = regions;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model = SofModel();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile('*.sof');
            if isequal(file,0)
                app.displayMessage('Openning file canceled');
            else
                [status, msg] = app.model.readConfig(fullfile(path, file));
                if status
                    app.projDir = path;
                    app.projFile = file;
                end
                app.displayMessage(msg);
            end
        end

        % Value changed function: GeomTemplate
        function GeomTemplateValueChanged(app, event)
            value = app.GeomTemplate.Value;
            selectGeomTemplate(app, value);
            updateGeomTab(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sofview = uifigure;
            app.Sofview.Name = 'Sofview';
            app.FileMenu = uimenu(app.Sofview);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.HelpMenu = uimenu(app.Sofview);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.Sofview);
            app.NewTab = uitab(app.TabGroup);
            app.NewTab.Title = 'New';
            app.GeometrytemplateDropDownLabel = uilabel(app.NewTab);
            app.GeometrytemplateDropDownLabel.Text = 'Geometry template';
            app.GeomTemplate = uidropdown(app.NewTab);
            app.GeomTemplate.Items = {'Square', 'Rectangle', 'L-Shape', 'SquareHole', 'None'};
            app.GeomTemplate.ValueChangedFcn = createCallbackFcn(app, @GeomTemplateValueChanged, true);
            app.GeomTemplate.Value = 'None';
            app.TextArea = uitextarea(app.NewTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Caution: changing geometry template clear all other settings'};
            app.GeomTab = uitab(app.TabGroup);
            app.GeomTab.Title = 'Geom';
            app.RegionsListBoxLabel = uilabel(app.GeomTab);
            app.RegionsListBoxLabel.Text = 'Regions';
            app.RegionsListBox = uilistbox(app.GeomTab);
            app.RegionsListBox.Items = {};
            app.RegionsListBox.Value = {};
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.SupportsTab = uitab(app.TabGroup);
            app.SupportsTab.Title = 'Supports';
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.Sofview);
            title(app.UIAxes, 'Title')
            app.Tree = uitree(app.Sofview);
            app.ModelNode = uitreenode(app.Tree);
            app.ModelNode.Text = 'Model';
            app.GeometryNode = uitreenode(app.ModelNode);
            app.GeometryNode.Text = 'Geometry';
            app.SupportsNode = uitreenode(app.ModelNode);
            app.SupportsNode.Text = 'Supports';
            app.LoadsNode = uitreenode(app.ModelNode);
            app.LoadsNode.Text = 'Loads';
            app.StatusmessageLabel = uilabel(app.Sofview);
            app.StatusmessageLabel.Text = 'Status message:';
            app.StatusInfo = uilistbox(app.Sofview);
            app.StatusInfo.Items = {};
            app.StatusInfo.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = sofview

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sofview)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sofview)
        end
    end
end